classdef appDecode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DECODINGUIFigure         matlab.ui.Figure
        SelectimageLabel         matlab.ui.control.Label
        THESYNDICATELabel        matlab.ui.control.Label
        DecodingLabel            matlab.ui.control.Label
        ImagesteganographyLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        DecodeButton             matlab.ui.control.Button
        key                      matlab.ui.control.EditField
        Button                   matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        pass % Description
        Secret % Description
    end
    
    methods (Access = private)
        
        function results = dec(app)
            
dimg = app.img;


pass = app.pass;
tic
%taking the length of the img
num_rows_img = length(dimg(:, 1, 1));
num_col_img = length(dimg(1, :, 1));

num_col_img = num_col_img - mod(num_col_img, 3);
pixels = num_col_img/3;
x = 1; pix = 1;
A = [];

asciipass = uint8(pass);
disp(asciipass);
value = 0;
for i = 1:length(pass)
    value = value + double(asciipass(i))^i;
end
value = mod(value, 40320);
if (value == 0)
    value = 40320;
end
put = [1,2,3,4,5,6,7,8];
vec = [0 0 0 0 0 0 0 0];
for i = 1:7
    l = value/factorial(8-i);
    r = ceil(l);
    vec(i) = put(r);
    value = value - floor(l)*factorial(8-i);
    put(r) = [];
    if (value == 0)
        break;
    end
end
if (value == 0)
    while(length(put) > 1)
        vec(9-length(put)) = put(1);
        put(1) = [];
    end
end
vec(8) = put(1);


pow = [128 64 32 16 8 4 2 1];

count = 1;
while(true)

    sav = [0 0 0 0 0 0 0 0];
    layer = 1;
    for j = (1 : 8)

         if(j == 4)
            layer = 2;
         elseif(j == 7)
            layer = 3;
         end

         y = (pix-1)*3 + mod(j, 3);
         if(mod(j, 3) == 0)
            y = (pix-1)*3 + 3;
         end

         z = vec(1, j);
         sav(1, z) = mod(dimg(x, y, layer), 2);

    end
    sav = sav.*pow;
    value = sum(sav);
    if(value < 32)
        value = value + 32;
    end
    A = [A; [value]];
    if(mod(dimg(x, (pix-1)*3 + 3, 3), 2) == 1)
         break;
    end
    pix = pix + 1;
    if(pix == pixels+1)
       x = x+1; pix = 1;
    end
end

A = A';
ans = char(A);
disp("Final text message is :-");
disp(ans);
app.Secret=ans;
toc
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function select(app, event)
            [file,path]=uigetfile('*.*');
             if isequal(file,0)
                figure(app.DECODINGUIFigure);
                return;
             end
             app.img=imread(fullfile(path,file));
             imshow(app.img,'Parent',app.UIAxes);
        end

        % Value changed function: key
        function keyValueChanged(app, event)
            app.pass = app.key.Value;
            
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            dec(app);
            app.TextArea.Value=(app.Secret);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DECODINGUIFigure = uifigure('Visible', 'off');
            app.DECODINGUIFigure.Name = 'DECODING';
            app.DECODINGUIFigure.Icon = fullfile(pathToMLAPP, 'logo.jpeg');
            app.UIAxes = uiaxes(app.DECODINGUIFigure);
            title(app.UIAxes, 'Stego Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Button = uibutton(app.DECODINGUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.Button.Icon = fullfile(pathToMLAPP, '—Pngtree—upload icon_4491176.png');
            app.Button.Text = '';
            app.key = uieditfield(app.DECODINGUIFigure, 'text');
            app.key.ValueChangedFcn = createCallbackFcn(app, @keyValueChanged, true);
            app.key.Placeholder = 'Key';
            app.DecodeButton = uibutton(app.DECODINGUIFigure, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            app.TextArea = uitextarea(app.DECODINGUIFigure);
            app.TextArea.Placeholder = 'Secret Message';
            app.ImagesteganographyLabel = uilabel(app.DECODINGUIFigure);
            app.ImagesteganographyLabel.Text = 'Image steganography';
            app.DecodingLabel = uilabel(app.DECODINGUIFigure);
            app.DecodingLabel.Text = 'Decoding';
            app.THESYNDICATELabel = uilabel(app.DECODINGUIFigure);
            app.THESYNDICATELabel.Text = 'THE SYNDICATE';
            app.SelectimageLabel = uilabel(app.DECODINGUIFigure);
            app.SelectimageLabel.Text = 'Select image';
            % Show the figure after all components are created
            app.DECODINGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDecode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DECODINGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DECODINGUIFigure)
        end
    end
end