classdef appEncode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ENCODINGUIFigure         matlab.ui.Figure
        SaveImageLabel           matlab.ui.control.Label
        SelectImageLabel         matlab.ui.control.Label
        THESYNDICATELabel        matlab.ui.control.Label
        EncodingLabel            matlab.ui.control.Label
        ImageSteganographyLabel  matlab.ui.control.Label
        PSNRLabel                matlab.ui.control.Label
        MSELabel                 matlab.ui.control.Label
        MSE                      matlab.ui.control.NumericEditField
        PSNR                     matlab.ui.control.NumericEditField
        Button_2                 matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        EncodeButton             matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        Button                   matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
        key % Description
        secret % Description
        b % Description
    end
    
    methods (Access = private)
        
        function results = steg(app)
            %reading the image and key

newimg = app.img;
pass = app.key;

%taking message input
mess = app.b;

tic
%length of the message or required points
len_mes = length(mess)*8;

%changing the value of string to numerical
Ascii_values = uint8(mess);
disp(Ascii_values);

%changing ascii values to the binary number
binary_str = dec2bin(Ascii_values, 8);

%having the coloumn length of binary
num_row_bin = length(binary_str(:, 1));
num_col_bin = 8;

%taking the length of the img
num_rows_img = length(newimg(:, 1, 1));
num_col_img = length(newimg(1, :, 1));

num_col_img = num_col_img - mod(num_col_img, 3);
pixels = num_col_img/3;
x = 1; pix = 1;
img = newimg;

asciipass = uint8(pass);
disp(asciipass);
value = 0;
for i = 1:length(pass)
    value = value + double(asciipass(i))^i;
   
end
value = mod(value, 40320);
if (value == 0)
    value = 40320;
end
put = [1,2,3,4,5,6,7,8];
vec = [0 0 0 0 0 0 0 0];
for i = 1:7
    l = value/factorial(8-i);
    r = ceil(l);
    vec(i) = put(r);
    value = value - floor(l)*factorial(8-i);
    put(r) = [];
    if (value == 0)
        break;
    end
end
if (value == 0)
    while(length(put) > 1)
        vec(9-length(put)) = put(1);
        put(1) = [];
    end
end
vec(8) = put(1);

if(pixels*num_rows_img < num_row_bin)
    disp("Message too long for this image :(");
    exit;
end

for i = (1 : num_row_bin)
    layer = 1;
    for j = (1 : 8)
         if(j == 4)
            layer = 2;
         elseif(j == 7)
            layer = 3;
         end

         y = (pix-1)*3 + mod(j, 3);
         if(mod(j, 3) == 0)
            y = (pix-1)*3 + 3;
         end

         z = vec(1, j);
         if(binary_str(i, z) == "0")
            img(x, y, layer) = img(x, y, layer) - mod(img(x, y, layer), 2);
         else
            if(mod(img(x, y, layer), 2) == 0)
              img(x, y, layer) = img(x, y, layer) + 1;
            end
         end
    end
    img(x, (pix-1)*3 + 3, 3) = img(x, (pix-1)*3 + 3, 3) - mod(img(x, (pix-1)*3 + 3, 3), 2);
    if(i == num_row_bin)
      img(x, (pix-1)*3 + 3, 3) = img(x, (pix-1)*3 + 3, 3) + 1;
    end
    pix = pix+1;
    if(pix == pixels+1)
        pix = 1; x = x+1;
    end
end
toc
app.img2=img;
imshow(app.img2,'parent',app.UIAxes2)

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function select(app, event)
            [file,path]=uigetfile('*.*');
             if isequal(file,0)
                figure(app.ENCODINGUIFigure);
                return;
             end
             app.img=imread(fullfile(path,file));
             imshow(app.img,'Parent',app.UIAxes);
            
        end

        % Value changed function: EditField
        function keyy(app, event)
            app.key = app.EditField.Value;
            
        end

        % Button pushed function: ClearButton
        function clear(app, event)
            app.PSNR.Value=0;
            app.MSE.Value=0;
            app.TextArea.Value='';
            app.EditField.Value='';
            cla(app.UIAxes);
            cla(app.UIAxes2);

        end

        % Button pushed function: EncodeButton
        function encode(app, event)
            steg(app);
            a=immse(app.img2,app.img);
            app.MSE.Value=a;
            b=PSNR(app.img2,app.img);
            app.PSNR.Value=b;
           
        end

        % Value changed function: TextArea
        function message(app, event)
            value = app.TextArea.Value;
            
   app.b = char(value); 

        end

        % Button pushed function: Button_2
        function Save(app, event)
            [file,path,index] = uiputfile
            filename=fullfile(path,file);
            imwrite(app.img2,filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ENCODINGUIFigure = uifigure('Visible', 'off');
            app.ENCODINGUIFigure.Name = 'ENCODING';
            app.ENCODINGUIFigure.Icon = fullfile(pathToMLAPP, 'logo.jpeg');
            app.UIAxes = uiaxes(app.ENCODINGUIFigure);
            title(app.UIAxes, 'Cover image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.ENCODINGUIFigure);
            title(app.UIAxes2, 'stego image')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.Button = uibutton(app.ENCODINGUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.Button.Text = '';
            app.Button.Icon = fullfile(pathToMLAPP, '—Pngtree—upload icon_4491176.png');
            app.EditField = uieditfield(app.ENCODINGUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @keyy, true);
            app.EditField.Placeholder = 'Enter Key';
            app.ClearButton = uibutton(app.ENCODINGUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.ClearButton.Text = 'Clear';
            app.EncodeButton = uibutton(app.ENCODINGUIFigure, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @encode, true);
            app.EncodeButton.Text = 'Encode';
            app.TextArea = uitextarea(app.ENCODINGUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @message, true);
            app.TextArea.Placeholder = 'Secret Message';
            app.Button_2 = uibutton(app.ENCODINGUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Save, true);
            app.Button_2.Text = '';
            app.Button_2.Icon = fullfile(pathToMLAPP, 'transparent-arrow-icon-download-icon-downloading-icon-5dc64f5397f8d5.4272582415732775236225.png');
            app.PSNR = uieditfield(app.ENCODINGUIFigure, 'numeric');
            app.MSE = uieditfield(app.ENCODINGUIFigure, 'numeric');
            app.MSELabel = uilabel(app.ENCODINGUIFigure);
            app.MSELabel.Text = 'MSE';
            app.PSNRLabel = uilabel(app.ENCODINGUIFigure);
            app.PSNRLabel.Text = 'PSNR';
            app.ImageSteganographyLabel = uilabel(app.ENCODINGUIFigure);
            app.ImageSteganographyLabel.Text = 'Image Steganography';
            app.EncodingLabel = uilabel(app.ENCODINGUIFigure);
            app.EncodingLabel.Text = 'Encoding';
            app.THESYNDICATELabel = uilabel(app.ENCODINGUIFigure);
            app.THESYNDICATELabel.Text = 'THE SYNDICATE';
            app.SelectImageLabel = uilabel(app.ENCODINGUIFigure);
            app.SelectImageLabel.Text = 'Select Image';
            app.SaveImageLabel = uilabel(app.ENCODINGUIFigure);
            app.SaveImageLabel.Text = 'Save Image';
            % Show the figure after all components are created
            app.ENCODINGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appEncode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ENCODINGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ENCODINGUIFigure)
        end
    end
end